classdef taller2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Taller2Label                matlab.ui.control.Label
        GrabarButton                matlab.ui.control.Button
        ReproducirButton            matlab.ui.control.Button
        PausarButton                matlab.ui.control.Button
        DetenerButton               matlab.ui.control.Button
        EfectosdeSonidoButtonGroup  matlab.ui.container.ButtonGroup
        NingunoButton               matlab.ui.control.RadioButton
        ReverberacionButton         matlab.ui.control.RadioButton
        FlangingButton              matlab.ui.control.RadioButton
        PanningButton               matlab.ui.control.RadioButton
        TremoloButton               matlab.ui.control.RadioButton
        EcoButton                   matlab.ui.control.RadioButton
        EcoSliderLabel              matlab.ui.control.Label
        EcoSlider                   matlab.ui.control.Slider
        ReverberacionSliderLabel    matlab.ui.control.Label
        ReverberacionSlider         matlab.ui.control.Slider
        FlangingSliderLabel         matlab.ui.control.Label
        FlangingSlider              matlab.ui.control.Slider
        PaningSliderLabel           matlab.ui.control.Label
        PaningSlider                matlab.ui.control.Slider
        TremoloSliderLabel          matlab.ui.control.Label
        TremoloSlider               matlab.ui.control.Slider
    end

    
    methods (Access = public)
        
        function starupFcn(app)
            global EfectoSonido;
            EfectoSonido = 'Reproducir.slx';
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GrabarButton
        function GrabarButtonPushed(app, event)
            clc;
            
            Duracion = 4; %tiempo de grabación en segundos
            Fs = 44100; %Frecuencia de muestreo
            
            %Crear un objeto de audio
            %En este caso es un mircrofono, que se va a muestrear
            %con Fs, una resolución de 16 bits y de un canal.
            %1 es mono 2 estereo
            
            Entrada=audiorecorder(Fs,16,1);
            %record(Entrada);
            %Abrir cuadro de dialogo
            msgbox('Grabando...','Titulo');
            %funcion para capturar el sonido
            recordblocking(Entrada,Duracion);
            msgbox('Finalizo la grabación','titulo');
            %Asignar audio capturado a una variable
            x=getaudiodata(Entrada,'int16');
            %guardar en un archivo de audio
            audiowrite('C:\Users\JonathanPC\Documents\MATLAB\taller2\Sonido.wav',x,Fs);
        end

        % Button pushed function: ReproducirButton
        function ReproducirButtonPushed(app, event)
            global EfectoSonido;
            ReproducirAudio = EfectoSonido;
            find_system('name',ReproducirAudio);
            load_system(ReproducirAudio);
            set_param(gcs,'SimulationCommand','Start');
            set_param(gcs,'SimulationCommand','Continue');
            pause(2)
            
            
            %Nombre_archivo = 'Sonido.wav';
            %Esta instrucción retorna el archivo de audio
            %En forma de vector (y) y la frecuencia de muestreo
            %Con la que fue grabado (Fs)
            %[y,Fs]=audioread(Nombre_archivo);
            %sound se usa para reproducir el sonido
            %sound(y,Fs);
        end

        % Button pushed function: PausarButton
        function PausarButtonPushed(app, event)
            %oldState = pause('on')
            %pause(app.Entrada);
            set_param(gcs,'SimulationCommand','Pause')
            pause(5)
        end

        % Button pushed function: DetenerButton
        function DetenerButtonPushed(app, event)
            set_param(gcs,'SimulationCommand','Stop');
        end

        % Selection changed function: EfectosdeSonidoButtonGroup
        function EfectosdeSonidoButtonGroupSelectionChanged(app, event)
            selectedButton = app.EfectosdeSonidoButtonGroup.SelectedObject;
            global EfectoSonido;
            if(selectedButton.Text == "Ninguno")
                disp('Ninguno')
                set(app.EcoSlider,'enable','off')
                set(app.ReverberacionSlider,'enable','off')
                set(app.FlangingSlider,'enable','off')
                set(app.PaningSlider,'enable','off')
                set(app.TremoloSlider,'enable','off')
                EfectoSonido = 'Reproducir.slx';
                
            elseif(selectedButton.Text == "Eco")
                disp('Eco')
                set(app.EcoSlider,'enable','on')
                set(app.ReverberacionSlider,'enable','off')
                set(app.FlangingSlider,'enable','off')
                set(app.PaningSlider,'enable','off')
                set(app.TremoloSlider,'enable','off')
                EfectoSonido = 'Eco.slx';
                
            elseif(selectedButton.Text == "Reverberacion")
                disp('Reverberacion')
                set(app.EcoSlider,'enable','off')
                set(app.ReverberacionSlider,'enable','on')
                set(app.FlangingSlider,'enable','off')
                set(app.PaningSlider,'enable','off')
                set(app.TremoloSlider,'enable','off')
                EfectoSonido = 'Reverberacion.slx';
                
            elseif(selectedButton.Text == "Flanging")
                disp('Flanging')
                set(app.EcoSlider,'enable','off')
                set(app.ReverberacionSlider,'enable','off')
                set(app.FlangingSlider,'enable','on')
                set(app.PaningSlider,'enable','off')
                set(app.TremoloSlider,'enable','off')
                EfectoSonido = 'Flanging.slx';
                
            elseif(selectedButton.Text == "Panning")
                disp('Panning')
                set(app.EcoSlider,'enable','off')
                set(app.ReverberacionSlider,'enable','off')
                set(app.FlangingSlider,'enable','off')
                set(app.PaningSlider,'enable','on')
                set(app.TremoloSlider,'enable','off')
                EfectoSonido = 'Panning.slx';
                
            elseif(selectedButton.Text == "Tremolo")
                disp('Tremolo')
                set(app.EcoSlider,'enable','off')
                set(app.ReverberacionSlider,'enable','off')
                set(app.FlangingSlider,'enable','off')
                set(app.PaningSlider,'enable','off')
                set(app.TremoloSlider,'enable','on')
                EfectoSonido = 'Tremolo.slx';
            else
                disp('No se encontro archivo');
            end
            find_system('name',EfectoSonido);
            %Cargar el archivo, sin abrir la ventana
            load_system(EfectoSonido);
        end

        % Value changing function: EcoSlider
        function EcoSliderValueChanging(app, event)
            changingValue = event.Value;
            %app.EditField.Value = event.Value;
            set_param('Eco/x[n-D]','DelayLength',int2str(changingValue));
        end

        % Value changing function: ReverberacionSlider
        function ReverberacionSliderValueChanging(app, event)
            changingValue = event.Value;
            set_param('Reverberacion/y[n-D]','DelayLength',int2str(changingValue));
        end

        % Value changing function: FlangingSlider
        function FlangingSliderValueChanging(app, event)
            changingValue = event.Value;
            set_param('Flanging/x','Frequency',int2str(changingValue));
        end

        % Value changing function: PaningSlider
        function PaningSliderValueChanging(app, event)
            changingValue = event.Value;
            set_param('Panning/a','SamplesPerFrame',int2str(changingValue));
        end

        % Value changing function: TremoloSlider
        function TremoloSliderValueChanging(app, event)
            changingValue = event.Value;
            set_param('Tremolo/a','Frequency',int2str(changingValue));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Taller2Label = uilabel(app.UIFigure);
            app.Taller2Label.Text = 'Taller 2';
            app.GrabarButton = uibutton(app.UIFigure, 'push');
            app.GrabarButton.ButtonPushedFcn = createCallbackFcn(app, @GrabarButtonPushed, true);
            app.GrabarButton.Text = 'Grabar';
            app.ReproducirButton = uibutton(app.UIFigure, 'push');
            app.ReproducirButton.ButtonPushedFcn = createCallbackFcn(app, @ReproducirButtonPushed, true);
            app.ReproducirButton.Text = 'Reproducir';
            app.PausarButton = uibutton(app.UIFigure, 'push');
            app.PausarButton.ButtonPushedFcn = createCallbackFcn(app, @PausarButtonPushed, true);
            app.PausarButton.Text = 'Pausar';
            app.DetenerButton = uibutton(app.UIFigure, 'push');
            app.DetenerButton.ButtonPushedFcn = createCallbackFcn(app, @DetenerButtonPushed, true);
            app.DetenerButton.Text = 'Detener';
            app.EfectosdeSonidoButtonGroup = uibuttongroup(app.UIFigure);
            app.EfectosdeSonidoButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EfectosdeSonidoButtonGroupSelectionChanged, true);
            app.EfectosdeSonidoButtonGroup.Title = 'Efectos de Sonido';
            app.NingunoButton = uiradiobutton(app.EfectosdeSonidoButtonGroup);
            app.NingunoButton.Text = 'Ninguno';
            app.NingunoButton.Value = true;
            app.ReverberacionButton = uiradiobutton(app.EfectosdeSonidoButtonGroup);
            app.ReverberacionButton.Text = 'Reverberacion';
            app.FlangingButton = uiradiobutton(app.EfectosdeSonidoButtonGroup);
            app.FlangingButton.Text = 'Flanging';
            app.PanningButton = uiradiobutton(app.EfectosdeSonidoButtonGroup);
            app.PanningButton.Text = 'Panning';
            app.TremoloButton = uiradiobutton(app.EfectosdeSonidoButtonGroup);
            app.TremoloButton.Text = 'Tremolo';
            app.EcoButton = uiradiobutton(app.EfectosdeSonidoButtonGroup);
            app.EcoButton.Text = 'Eco';
            app.EcoSliderLabel = uilabel(app.UIFigure);
            app.EcoSliderLabel.Text = 'Eco';
            app.EcoSlider = uislider(app.UIFigure);
            app.EcoSlider.ValueChangingFcn = createCallbackFcn(app, @EcoSliderValueChanging, true);
            app.ReverberacionSliderLabel = uilabel(app.UIFigure);
            app.ReverberacionSliderLabel.Text = 'Reverberacion';
            app.ReverberacionSlider = uislider(app.UIFigure);
            app.ReverberacionSlider.ValueChangingFcn = createCallbackFcn(app, @ReverberacionSliderValueChanging, true);
            app.FlangingSliderLabel = uilabel(app.UIFigure);
            app.FlangingSliderLabel.Text = 'Flanging';
            app.FlangingSlider = uislider(app.UIFigure);
            app.FlangingSlider.ValueChangingFcn = createCallbackFcn(app, @FlangingSliderValueChanging, true);
            app.PaningSliderLabel = uilabel(app.UIFigure);
            app.PaningSliderLabel.Text = 'Paning';
            app.PaningSlider = uislider(app.UIFigure);
            app.PaningSlider.ValueChangingFcn = createCallbackFcn(app, @PaningSliderValueChanging, true);
            app.TremoloSliderLabel = uilabel(app.UIFigure);
            app.TremoloSliderLabel.Text = 'Tremolo';
            app.TremoloSlider = uislider(app.UIFigure);
            app.TremoloSlider.ValueChangingFcn = createCallbackFcn(app, @TremoloSliderValueChanging, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = taller2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end